classdef exp6_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        FileMenu       matlab.ui.container.Menu
        OpenMenuItem   matlab.ui.container.Menu
        PrintMenuItem  matlab.ui.container.Menu
        CloseMenuItem  matlab.ui.container.Menu
        text1          matlab.ui.control.Label
        pushbutton1    matlab.ui.control.Button
        popupmenu1     matlab.ui.control.DropDown
        axes1          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function exp6_1_OpeningFcn(app, varargin)
            % --- Executes just before exp6_1 is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to exp6_1 (see VARARGIN)
            
            % Choose default command line output for exp6_1
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % This sets up the initial plot - only do when we are invisible
            % so window can get raised using exp6_1.
            if strcmp(get(hObject,'Visible'),'off')
                plot(rand(5));
            end
        end

        % Menu selected function: CloseMenuItem
        function CloseMenuItem_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CloseMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selection = questdlg(['Close ' get(handles.figure1,'Name') '?'],...
                                 ['Close ' get(handles.figure1,'Name') '...'],...
                                 'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            
            delete(handles.figure1)
        end

        % Menu selected function: OpenMenuItem
        function OpenMenuItem_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to OpenMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            file = uigetfile('*.fig');
            if ~isequal(file, 0)
                open(file);
            end
        end

        % Menu selected function: PrintMenuItem
        function PrintMenuItem_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PrintMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            printdlg(handles.figure1)
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes1);
            cla;
            
            popup_sel_index = get(handles.popupmenu1, 'Value');
            switch popup_sel_index
                case 1
                    plot(rand(5));
                case 2
                    plot(sin(1:0.01:25.99));
                case 3
                    bar(1:.5:10);
                case 4
                    plot(membrane);
                case 5
                    surf(peaks);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Untitled';
            app.figure1.Tag = 'figure1';
            app.FileMenu = uimenu(app.figure1);
            app.FileMenu.Text = 'File';
            app.FileMenu.Tag = 'FileMenu';
            app.OpenMenuItem = uimenu(app.FileMenu);
            app.OpenMenuItem.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuItem_Callback, true);
            app.OpenMenuItem.Text = 'Open ...';
            app.OpenMenuItem.Tag = 'OpenMenuItem';
            app.PrintMenuItem = uimenu(app.FileMenu);
            app.PrintMenuItem.MenuSelectedFcn = createCallbackFcn(app, @PrintMenuItem_Callback, true);
            app.PrintMenuItem.Text = 'Print ...';
            app.PrintMenuItem.Tag = 'PrintMenuItem';
            app.CloseMenuItem = uimenu(app.FileMenu);
            app.CloseMenuItem.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuItem_Callback, true);
            app.CloseMenuItem.Separator = 'on';
            app.CloseMenuItem.Text = 'Close';
            app.CloseMenuItem.Tag = 'CloseMenuItem';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.ZLim = [0 1];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTick = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6];
            app.axes1.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'; '1.2'; '1.4'; '1.6'};
            app.axes1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.axes1.ZTick = [0 0.5 1];
            app.axes1.ZTickLabel = '';
            app.axes1.Tag = 'axes1';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'plot(rand(5))', 'plot(sin(1:0.01:25))', 'bar(1:.5:10)', 'plot(membrane)', 'surf(peaks)'};
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'plot(rand(5))';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'UPDATE';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = '+  EXP  6-1  +';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp6_1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)exp6_1_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end