classdef exp6_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EXP64Label   matlab.ui.control.Label
        ButtonGroup  matlab.ui.container.ButtonGroup
        BlueButton   matlab.ui.control.RadioButton
        GreenButton  matlab.ui.control.RadioButton
        RedButton    matlab.ui.control.RadioButton
        TextArea     matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ButtonGroup.SelectedObject;
            if app.RedButton.Value
                app.TextArea.BackgroundColor = [0.64 0.08 0.18]; % Red
            elseif app.GreenButton.Value
                app.TextArea.BackgroundColor = [0.47 0.67 0.19]; % Green
            elseif app.BlueButton.Value
                app.TextArea.BackgroundColor = [0.00 0.45 0.74]; % Blue
            else
                app.TextArea.BackgroundColor = [1 1 1]; % Default: White
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {''; 'Change My Color'; ''};
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.RedButton = uiradiobutton(app.ButtonGroup);
            app.RedButton.Text = 'Red';
            app.RedButton.Value = true;
            app.GreenButton = uiradiobutton(app.ButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uiradiobutton(app.ButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.EXP64Label = uilabel(app.UIFigure);
            app.EXP64Label.Text = '+  EXP  6-4  +';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp6_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end